classdef forceGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ColourButton              matlab.ui.control.Button
        mVtoVCheckBox             matlab.ui.control.CheckBox
        CoVwinEditField           matlab.ui.control.EditField
        CoVwinEditFieldLabel      matlab.ui.control.Label
        SampFreqHzEditField       matlab.ui.control.NumericEditField
        SampFreqHzEditFieldLabel  matlab.ui.control.Label
        FlipButton                matlab.ui.control.Button
        Hyperlink                 matlab.ui.control.Hyperlink
        HardResetButton           matlab.ui.control.Button
        RedoCheckBox              matlab.ui.control.CheckBox
        RightEditField            matlab.ui.control.NumericEditField
        RightEditFieldLabel       matlab.ui.control.Label
        LeftEditField             matlab.ui.control.NumericEditField
        LeftEditFieldLabel        matlab.ui.control.Label
        MultiDropDown             matlab.ui.control.DropDown
        MultiDropDownLabel        matlab.ui.control.Label
        EditCalibButton           matlab.ui.control.Button
        ShowTargetCheckBox        matlab.ui.control.CheckBox
        CutoffHzEditField         matlab.ui.control.NumericEditField
        CutoffHzEditFieldLabel    matlab.ui.control.Label
        RigDropDown               matlab.ui.control.DropDown
        RigDropDownLabel          matlab.ui.control.Label
        AnalysisDropDown          matlab.ui.control.DropDown
        AnalysisDropDownLabel     matlab.ui.control.Label
        ChannelDropDown           matlab.ui.control.DropDown
        ChannelDropDownLabel      matlab.ui.control.Label
        ModeDropDown              matlab.ui.control.DropDown
        ModeDropDownLabel         matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        FilterButton              matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        ExportResultsButton       matlab.ui.control.Button
        ComputeMetricsButton      matlab.ui.control.Button
        SelectSegmentButton       matlab.ui.control.Button
        LoadFileButton            matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        FilePath   % full path to loaded .mat file
        Data       % struct from loadData
        metrics    % struct from computeMetrics
        Results    % struct from buildResultsStruct
        Code       % identifier based on filename
        PerfFilt        % filtered performance signal
        TargetFilt      % filtered target signal
        forcewave       % selected segment (force)
        targetwave      % selected segment (target)
        roi   % handle to the current rectangle
        highlightLine
        Perf
        Target
        ramp1ROI
        ramp1Line
        plateauROI
        plateauLine
        ramp2ROI
        ramp2Line
        Segments
        targetPlot
        metricsRamp
        ResultsRamp
        ResultsCombined
        CurrentContraction
        LeftScale
        RightScale
        alreadyProcessed
        ContractionLabels
        Calib

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'forceGUI v0.1.4-alpha';
            fprintf('--------------------------------------------------\n');
            fprintf('Hello there, you are using %s\n', app.UIFigure.Name);
            fprintf('This is a dev build and prone to bugs\n');
            fprintf('--------------------------------------------------\n');
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)

            % should we reset before everything
            app.HardResetButtonPushed()

            app.Segments = struct();          % single-contraction default
            app.ResultsCombined = struct();   % clear multi-contraction results
                  

            [file, path] = uigetfile('*.mat');
            if isequal(file,0); return; end

            app.FilePath = fullfile(path,file);

            % this for ephys
            % wrap this in try catch loop
            % try generic loading

            try
                template = selectTemplate( ...
                    app.ModeDropDown.Value, ...
                    app.RigDropDown.Value, ...
                    app.AnalysisDropDown.Value, ...
                    app.RedoCheckBox.Value);

                % --- REDO CASE ---
                if isstring(template) && template == "redo"
                    temp = loadData_redo(app.FilePath);

                    % populate app fields directly
                    f = fieldnames(temp);
                    for k = 1:numel(f)
                        fieldName = f{k};
                        if isprop(app, fieldName)
                            try
                                app.(fieldName) = temp.(fieldName);
                            catch
                            end
                        end
                    end

                    app.alreadyProcessed = true;

                else

                    % --- NORMAL CASE ---
                    loaded = loadData_generic(app.FilePath, template);
                    app.Data = loaded;
                end

            catch ME
                uialert(app.UIFigure, ...
                    sprintf("File '%s' does not match expected format for:\nMode: %s\nRig: %s\nAnalysis: %s\n\nDetails:\n%s", ...
                    file, ...
                    app.ModeDropDown.Value, ...
                    app.RigDropDown.Value, ...
                    app.AnalysisDropDown.Value, ...
                    ME.message), ...
                    'Invalid File');
                return;
            end


            % old code here
            % try
            %     if strcmp(app.AnalysisDropDown.Value, 'TRAP_multi')
            %         app.Data = loadData_multiContract(app.FilePath);
            % 
            %     elseif app.RedoCheckBox.Value
            %         temp = loadData_redo(app.FilePath);
            %         % now refill
            %         f = fieldnames(temp);
            %         for k = 1:numel(f)
            %             fieldName = f{k};
            %             if isprop(app, fieldName)
            %                 try
            %                     app.(fieldName) = temp.(fieldName);
            %                 catch
            %                     % ignore fields that cannot be assigned
            %                 end
            %             end
            %         end
            %         app.alreadyProcessed = true;
            % 
            %     else
            %         if strcmp(app.ModeDropDown.Value, 'Single Channel')
            %             if strcmp(app.RigDropDown.Value, 'Derby Chair')
            %                 if strcmp(app.AnalysisDropDown.Value, 'TRAP')
            %                     % TRAP Derby Chair → special loader
            %                     app.Data = loadData_derby(app.FilePath);
            %                 elseif strcmp(app.AnalysisDropDown.Value, 'MCON')
            %                     % MCON Derby Chair → normal single-channel loader
            %                     app.Data = loadData(app.FilePath);
            %                 elseif strcmp(app.AnalysisDropDown.Value,'RAMP')
            %                     app.Data = loadData_ramp(app.FilePath);
            %                 end
            %             elseif strcmp(app.AnalysisDropDown.Value,'RAMP')
            %                 app.Data = loadData_ramp(app.FilePath);
            %             else
            %                 % Normal rigs (ankle, hand, knee, arm)
            %                 app.Data = loadData(app.FilePath);
            %             end
            %         else
            %             % Multi-channel (Derby Chair is never allowed here)
            %             app.Data = loadData_multi(app.FilePath);
            %         end
            %     end
            % catch ME
            %     uialert(app.UIFigure, ...
            %         sprintf("File '%s' does not match expected format for:\nMode: %s\nRig: %s\nAnalysis: %s\n\nDetails:\n%s", ...
            %         file, ...
            %         app.ModeDropDown.Value, ...
            %         app.RigDropDown.Value, ...
            %         app.AnalysisDropDown.Value, ...
            %         ME.message), ...
            %         'Invalid File');
            %     return;
            % end

            [~, base, ~] = fileparts(file);
            app.Code = base;

            % --- TOP BAR TITLE UPDATE ---
            app.UIFigure.Name = sprintf('Loaded - [%s]', file);

            cla(app.UIAxes);  % clear raw axes
            
            %% big loop for plotting
            if strcmp(app.AnalysisDropDown.Value, 'TRAP_multi')
                % dont want to plot multi stuff yet!
                app.MultiDropDown.Enable = 'on';
                n = numel(app.Data);
                
                [folder,~,~] = fileparts(app.FilePath);
                labels = loadContractionLabels(folder);

                if numel(labels) ~= n
                    labels = arrayfun(@(x) sprintf('%d', x), 1:n, 'UniformOutput', false);
                end
                % store for later
                app.ContractionLabels = labels;

                items = arrayfun(@(i) sprintf('Con %d  %s', i, labels{i}), 1:n, 'UniformOutput', false);
                
                %items = arrayfun(@(x) sprintf('Con %d', x), 1:n, 'UniformOutput', false);
                app.MultiDropDown.Items = items;
                app.MultiDropDown.Value = items{1};
                MultiDropDownValueChanged(app, struct());


            else
                if strcmp(app.ModeDropDown.Value, 'Single Channel')
                    if strcmp(app.RigDropDown.Value, 'Derby Chair')
                        % MCON must have a target
                        if strcmp(app.AnalysisDropDown.Value, 'MCON')
                            perf = app.Data.Force;
                            target = app.Data.Target;

                            x = getTimeAxis(app.Data, length(perf));

                            app.targetPlot = plot(app.UIAxes, x, target, 'b'); hold(app.UIAxes,'on');
                            plot(app.UIAxes, x, perf, 'r', 'LineWidth',2);
                            
                        else
                            perf = app.Data.Force;
                            x = getTimeAxis(app.Data, length(perf));
                            plot(app.UIAxes, x, perf, 'r', 'LineWidth',2);
                            
                        end
                    elseif strcmp(app.AnalysisDropDown.Value,'RAMP')
                        perf = app.Data.Force;
                        x = getTimeAxis(app.Data, length(perf));
                        plot(app.UIAxes, x, perf, 'r', 'LineWidth',2);
                        
                    else
                        % uncomment this to show target again
                        perf = app.Data.Force;
                        x = getTimeAxis(app.Data, length(perf));
                        plot(app.UIAxes, x, perf, 'r', 'LineWidth', 2); hold(app.UIAxes,'on');

                        if isfield(app.Data, 'Target') && ~isempty(app.Data.Target)
                            target = app.Data.Target;
                            x = getTimeAxis(app.Data, length(perf));
                            app.targetPlot = plot(app.UIAxes,x, target, 'b');
                        end
                        
                    end

                elseif strcmp(app.ModeDropDown.Value, 'Multi Channel')
                    % Multi-channel: choose channel
                    ch = app.ChannelDropDown.Value;
                    switch ch
                        case 'Left'
                            perf = app.Data.ForceL;
                            target = getTargetOrEmpty(app.Data, 'TargetL');
                        case 'Right'
                            perf = app.Data.ForceR;
                            target = getTargetOrEmpty(app.Data, 'TargetR');
                        case 'Bilateral'
                            perf = app.Data.ForceBi;
                            target = getTargetOrEmpty(app.Data, 'TargetBi');
                    end
                    %targetScaled = scaleTarget(target, perf);
                    x = getTimeAxis(app.Data, length(perf));
                    
                    plot(app.UIAxes, x, perf, 'r', 'LineWidth', 2); hold(app.UIAxes,'on');
                    if ~isempty(target)
                        plot(app.UIAxes, x, target, 'b', 'LineWidth', 1);
                    end

                elseif strcmp(app.ModeDropDown.Value, 'Dual Channel')
                    % adding this for unique data
                    ch = app.ChannelDropDown.Value;
                    switch ch
                        case 'Left'
                            perf = app.Data.ForceL;
                        case 'Right'
                            perf = app.Data.ForceR;
                        case 'Bilateral'
                            if isfield(app.Data,'ForceBi')
                                perf = app.Data.ForceBi;
                            else
                                uialert(app.UIFigure, ...
                                    sprintf("Please just select left or right channel"), ...
                                    'Dual Channel Error');
                            end

                        
                    end
                    x = getTimeAxis(app.Data, length(perf));
                    
                    plot(app.UIAxes, x, perf, 'r', 'LineWidth', 2); hold(app.UIAxes,'on');

                end
                hold(app.UIAxes, 'off');
                if isfield(app.Data, 'Target') && ~isempty(app.Data.Target)
                    legend(app.UIAxes, {'Target','Perf'}, 'FontSize',8);
                else
                    legend(app.UIAxes, {'Perf'}, 'FontSize',8);
                end

            end
            %%
            grid(app.UIAxes,'on')

            % options here for redo
            if app.alreadyProcessed == true

                % Skip segmentation
                app.SelectSegmentButton.Enable = 'off';
                app.FilterButton.Enable = 'off';
                app.ComputeMetricsButton.Enable = 'on';
            else
                app.FilterButton.Enable = 'on';
                app.SelectSegmentButton.Enable = 'off';
                app.ComputeMetricsButton.Enable = 'off';
                app.ExportResultsButton.Enable = 'off';
            end

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            % disable
            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                % Ignore filter in multi mode
                return;
            end


            if strcmp(app.ModeDropDown.Value, 'Single Channel')
                if strcmp(app.RigDropDown.Value, 'Derby Chair')
                    if strcmp(app.AnalysisDropDown.Value, 'TRAP') || strcmp(app.AnalysisDropDown.Value, 'RAMP')
                        % TRAP or RAMP Derby Chair → no target
                        perfRaw   = app.Data.Force;
                        targetRaw = [];
                    else
                        % MCON Derby Chair → normal single-channel data with target
                        perfRaw   = app.Data.Force;
                        targetRaw = app.Data.Target;
                    end
                elseif strcmp(app.AnalysisDropDown.Value, 'RAMP') 
                    perfRaw   = app.Data.Force;
                    targetRaw = [];
                else
                    % Normal rigs (ankle, hand, knee, arm)
                    perfRaw   = app.Data.Force;
                    if isfield(app.Data,'Target')
                        targetRaw = app.Data.Target;
                    else
                        targetRaw = [];
                    end
                end
            elseif strcmp(app.ModeDropDown.Value, 'Multi Channel')
                % --- MULTI-CHANNEL ---
                ch = app.ChannelDropDown.Value;

                if strcmp(app.AnalysisDropDown.Value, 'TRAP') || strcmp(app.AnalysisDropDown.Value, 'RAMP') || strcmp(app.AnalysisDropDown.Value, 'MVC')
                    switch ch
                        case 'Left'
                            perfRaw = app.Data.ForceL;
                            targetRaw = getTargetOrEmpty(app.Data, 'TargetL');
                        case 'Right'
                            perfRaw = app.Data.ForceR;
                            targetRaw = getTargetOrEmpty(app.Data, 'TargetR');
                        case 'Bilateral'
                            if isfield(app.Data,'ForceL') && isfield(app.Data,'ForceR')
                                perfRaw = [app.Data.ForceL, app.Data.ForceR];
                            elseif isfield(app.Data,'ForceBi')
                                perfRaw = app.Data.ForceBi; % no separate L/R available, fall back
                            end
                            targetRaw = getTargetOrEmpty(app.Data, 'TargetBi');
                    end
                else % mcon
                    switch ch
                        case 'Left'
                            perfRaw = app.Data.ForceL;
                            targetRaw = getTargetOrEmpty(app.Data, 'TargetL');
                        case 'Right'
                            perfRaw = app.Data.ForceR;
                            targetRaw = getTargetOrEmpty(app.Data, 'TargetR');
                        case 'Bilateral'
                            if isfield(app.Data,'ForceBi') && ~isempty(app.Data.ForceBi)
                                perfRaw = app.Data.ForceBi;
                            elseif isfield(app.Data,'ForceL') && isfield(app.Data,'ForceR')
                                perfRaw = app.Data.ForceL + app.Data.ForceR;
                            end
                            targetRaw = getTargetOrEmpty(app.Data, 'TargetBi');
                    end
                end

            elseif strcmp(app.ModeDropDown.Value, 'Dual Channel')
                % --- DUAL-CHANNEL ---
                ch = app.ChannelDropDown.Value;
                targetRaw = [];
                if strcmp(app.AnalysisDropDown.Value, 'TRAP')
                    switch ch
                        case 'Left'
                            perfRaw = app.Data.ForceL;
                        case 'Right'
                            perfRaw = app.Data.ForceR;
                        case 'Bilateral'
                            % why is this happening? think maybe for calibration
                            perfRaw = [app.Data.ForceL, app.Data.ForceR];
                    end
                else
                    switch ch
                        case 'Left'
                            perfRaw = app.Data.ForceL;
                        case 'Right'
                            perfRaw = app.Data.ForceR;
                        case 'Bilateral'
                            perfRaw = app.Data.ForceBi;
                    end
                end


            end
            
            % -- CALIBRATION STEP ---
            rig = app.RigDropDown.Value;
            app.Calib = defaultCalibration();

            % For Derby Chair and Ankle → use defaults only
            if ~strcmp(rig, 'Derby Chair') && ~strcmp(rig, 'Ankle')
                % For other rigs (Hand, Knee, Arm) override Left/Right scale
                % Read GUI values
                L = app.LeftEditField.Value;
                R = app.RightEditField.Value;

                if strcmp(rig, 'Custom')
                    thisChannel = lower(app.ChannelDropDown.Value);

                    needL = strcmp(thisChannel, 'left')  || strcmp(thisChannel, 'bilateral');
                    needR = strcmp(thisChannel, 'right') || strcmp(thisChannel, 'bilateral');

                    if needL && L == 0
                        uialert(app.UIFigure, 'Custom rig requires a Left value for this channel.', 'Missing Calibration');
                        return
                    end
                    if needR && R == 0
                        uialert(app.UIFigure, 'Custom rig requires a Right value for this channel.', 'Missing Calibration');
                        return
                    end

                    if needL, app.Calib.custom.Left.scale  = L; else, app.Calib.custom.Left.scale  = 1; end
                    if needR, app.Calib.custom.Right.scale = R; else, app.Calib.custom.Right.scale = 1; end
                else

                    % If user hasn't typed anything, keep defaults
                    useDefaultL = (L == 0);
                    useDefaultR = (R == 0);

                    switch rig
                        case 'Hand'
                            if ~useDefaultL, app.Calib.hand.Left.scale  = L; end
                            if ~useDefaultR, app.Calib.hand.Right.scale = R; end

                        case 'Knee'
                            if ~useDefaultL, app.Calib.knee.Left.scale  = L; end
                            if ~useDefaultR, app.Calib.knee.Right.scale = R; end

                        case 'Arm'
                            if ~useDefaultL, app.Calib.arm.Left.scale   = L; end
                            if ~useDefaultR, app.Calib.arm.Right.scale  = R; end
                    end
                end
            end

            if strcmp(rig, 'Derby Chair')
                [perfCal, targetCal] = calibrateDerby(perfRaw, targetRaw, app.AnalysisDropDown.Value, app.Calib);
            elseif strcmp(app.AnalysisDropDown.Value, 'TRAP') || strcmpi(app.AnalysisDropDown.Value,'RAMP') || strcmpi(app.AnalysisDropDown.Value,'MVC')
                thisChannel = app.ChannelDropDown.Value;
                [perfCal, targetCal] = calibrateTRAP(perfRaw, rig, thisChannel, targetRaw, app.Calib);
            % elseif strcmpi(app.AnalysisDropDown.Value,'RAMP')
            %     % placeholder for now
            %     perfCal   = perfRaw;
            %     targetCal = targetRaw;


            else
                % MCON normal rigs
                perfCal   = perfRaw;
                targetCal = targetRaw;
            end


            % now set in app
            app.Perf   = perfCal;
            app.Target = targetCal;

            % option to give it sampling freq yourself. 
            if ~any(app.SampFreqHzEditField.Value)
                fs = app.Data.fs;
            else
                fs = app.SampFreqHzEditField.Value;
            end

            % Shift to zero baseline using mean of first 0.5 s
            if strcmpi(app.AnalysisDropDown.Value, 'MVC')
                % MVC rises almost immediately - just anchor to the very start of the trace
                nBaseline = min(round(0.01 * fs), length(app.Perf)); % ~10ms
                baseline = mean(app.Perf(1:nBaseline));
                % for now dont baseline the MVC
                % problem is some people are starting to push before
                % recording, so we just need the peak
                % undo this temp fix when EMG reader is fixed, to add a few
                % seconds of baseline recording before MVC - MA 3/8/26
                app.Perf = app.Perf;
            elseif strcmpi(app.AnalysisDropDown.Value, 'MCON')
                % DONT BASELINE SHIFT MCON, 
                % because the errors will be altered if the vertical shift
                % happens
                app.Perf   = app.Perf;
                app.Target = app.Target;

            else
                % % Shift to zero baseline using mean of first 0.5 s
                nBaseline = min(round(0.5 * fs), length(app.Perf));
                baseline = mean(app.Perf(1:nBaseline));
                %app.Perf = abs(app.Perf - baseline); % removing abs here
                app.Perf   = app.Perf - baseline;

                if ~isempty(app.Target)
                    nBaseline_t = min(round(0.5 * fs), length(app.Target));
                    baseline_t  = mean(app.Target(1:nBaseline_t));
                    %app.Target = abs(app.Target - baseline_t); % removing abs here
                    app.Target  = app.Target - baseline_t;
                end
                
            end

            % here is the filter, set to 20 - change?
            cutoff = app.CutoffHzEditField.Value; %usually 20
           
            % filter
            app.PerfFilt   = lpbutter(app.Perf, cutoff, fs);

            if isempty(app.Target)
                app.TargetFilt = [];
            else
                app.TargetFilt = lpbutter(app.Target, cutoff, fs);
            end

            % Clear the axis completely
            cla(app.UIAxes);
            legendEntries = {};
            if app.ShowTargetCheckBox.Value
                x = getTimeAxis(app.Data, length(app.TargetFilt));

                app.targetPlot = plot(app.UIAxes, x, app.TargetFilt, 'b');
                legendEntries{end+1} = 'TargetFilt';
                hold(app.UIAxes, 'on');
            end

            x = getTimeAxis(app.Data, length(app.PerfFilt));

            % Plot filtered versions
            if strcmp(app.RigDropDown.Value, 'Derby Chair')
                if strcmp(app.AnalysisDropDown.Value,'MCON')
                    %app.targetPlot = plot(app.UIAxes, app.TargetFilt, 'b');
                    %hold(app.UIAxes, 'on');
                    
                    plot(app.UIAxes, x, app.PerfFilt, 'r', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                    %legend(app.UIAxes, {'TargetFilt','PerfFilt'});
                else
                    plot(app.UIAxes, x, app.PerfFilt, 'r', 'LineWidth', 2);
                    %legend(app.UIAxes, {'ForceFilt'});
                end
            else
                %app.targetPlot = plot(app.UIAxes, app.TargetFilt, 'b');
                %hold(app.UIAxes, 'on');
                plot(app.UIAxes, x, app.PerfFilt, 'r', 'LineWidth', 2);
                hold(app.UIAxes, 'off');
                %legend(app.UIAxes, {'TargetFilt','PerfFilt'});
            end
            grid(app.UIAxes,'on')

            legendEntries{end+1} = 'PerfFilt';

            legend(app.UIAxes, legendEntries);


            if strcmpi(app.AnalysisDropDown.Value, 'MVC')
                app.SelectSegmentButton.Enable  = 'off';
                app.ComputeMetricsButton.Enable = 'on';
            else
                app.SelectSegmentButton.Enable = 'on';
                app.ComputeMetricsButton.Enable = 'off';
            end
            app.ExportResultsButton.Enable = 'off';

            % app.SelectSegmentButton.Enable = 'on';
            % app.ComputeMetricsButton.Enable = 'off';
            % app.ExportResultsButton.Enable = 'off';

            % --- USE SAME RAMP IDS FOR MULTI CHANNEL - need this here
            % as ChannelDropDown will bring us here to reuse segments
            if strcmp(app.ModeDropDown.Value, 'Multi Channel') && ...
                    isstruct(app.Segments) && isfield(app.Segments, 'Plateau') && ...
                    ~isempty(app.Segments.Plateau)

                idx1 = max(1, app.Segments.Plateau(1));
                idx2 = min(length(app.PerfFilt), app.Segments.Plateau(2));

                if idx2 > idx1
                    app.forcewave = app.PerfFilt(idx1:idx2);
                    if ~isempty(app.TargetFilt) && length(app.TargetFilt) >= idx2
                        app.targetwave = app.TargetFilt(idx1:idx2);
                    else
                        app.targetwave = [];
                    end
                    app.ComputeMetricsButton.Enable = 'on';
                end
            end


        end

        % Button pushed function: SelectSegmentButton
        function SelectSegmentButtonPushed(app, event)
            % defensive coding
            if isempty(app.Data)
                uialert(app.UIFigure, ...
                    'No data loaded. Load a file first.', ...
                    'Selection Error');
                return;
            end
            
            % multi contract defence
            isMulti = strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi');
            if isMulti; ddex = app.CurrentContraction; else; ddex = 1; end

            if isempty(app.PerfFilt) %|| isempty(app.TargetFilt)
                uialert(app.UIFigure, ...
                    'You must filter the signals before selecting a segment.', ...
                    'Selection Error');
                return;
            end

            % probably unnecessary
            if length(app.PerfFilt) < 10
                uialert(app.UIFigure, ...
                    'Filtered signals look invalid. Please filter again.', ...
                    'Selection Error');
                return;
            end
        
            app.UITable.Data = []; % clear table
            app.Results = [];   % also clear stored results

            N = length(app.PerfFilt);
            
            % plotting
            cla(app.UIAxes);

            if app.ShowTargetCheckBox.Value
                x = getTimeAxis(app.Data, length(app.TargetFilt));
                app.targetPlot = plot(app.UIAxes,x, app.TargetFilt, 'b');
                hold(app.UIAxes, 'on');
            else
                app.targetPlot = [];   % important so callback knows it's missing
                hold(app.UIAxes, 'on');
            end
            x = getTimeAxis(app.Data, length(app.PerfFilt));
            plot(app.UIAxes, x, app.PerfFilt, 'r', 'LineWidth',2);
            grid(app.UIAxes,'on')


            % -- DRAWING PHASE ---
            uialert(app.UIFigure, 'Draw rectangle for UP RAMP', 'Segment 1');
            app.ramp1ROI = drawrectangle(app.UIAxes);
            pos = app.ramp1ROI.Position;
            % r1_start = round(pos(1));
            % r1_end   = round(pos(1) + pos(3));
            % fix for x axis
            
            r1_start = round(pos(1) * app.Data(ddex).fs) + 1;
            r1_end   = round((pos(1) + pos(3)) * app.Data(ddex).fs) + 1;

            % defensive
            r1_start = max(1, r1_start);
            r1_end   = min(N, r1_end);

            app.ramp1Line = plot(app.UIAxes, x(r1_start:r1_end), app.PerfFilt(r1_start:r1_end), ...
                'Color',[0.2 0.7 0.2], 'LineWidth', 2.5);
            % Save for later
            %r1 = app.ramp1ROI.Position;
            %app.Segments.Ramp1 = [round(r1(1)), round(r1(1)+r1(3))];
            %app.Segments.Ramp1 = [r1_start, r1_end];

            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                ii = app.CurrentContraction;
                app.Segments(ii).Ramp1 = [r1_start, r1_end];
            else
                app.Segments.Ramp1 = [r1_start, r1_end];
            end


            % delete rectangle
            delete(app.ramp1ROI);
            app.ramp1ROI = [];

            uialert(app.UIFigure, 'Draw rectangle for DOWN RAMP', 'Segment 2');
            app.ramp2ROI = drawrectangle(app.UIAxes);
            pos = app.ramp2ROI.Position;
            % r2_start = round(pos(1));
            % r2_end   = round(pos(1) + pos(3));
            % fix for x axis
            r2_start = round(pos(1) * app.Data(ddex).fs) + 1;
            r2_end   = round((pos(1) + pos(3)) * app.Data(ddex).fs) + 1;

            % defensive coding in case rectanlge bigger than trace
            r2_start = max(1, r2_start);
            r2_end   = min(N, r2_end);

            app.ramp2Line = plot(app.UIAxes, x(r2_start:r2_end), app.PerfFilt(r2_start:r2_end), ...
                'Color',[0 0.6 1], 'LineWidth', 2.5);
            % save
            %r2 = app.ramp2ROI.Position;
            %app.Segments.Ramp2 = [round(r2(1)), round(r2(1)+r2(3))];
            %app.Segments.Ramp2 = [r2_start, r2_end];

            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                app.Segments(ii).Ramp2 = [r2_start, r2_end];
            else
                app.Segments.Ramp2 = [r2_start, r2_end];
            end

            % delete
            delete(app.ramp2ROI);
            app.ramp2ROI = [];

            % instead of getting an extra rectangle for plateau, just take
            % diff of r1 end and r2 start
            plateau_start = r1_end + 1;
            plateau_end   = r2_start - 1;

            % Safety clamp
            plateau_start = max(1, plateau_start);
            plateau_end   = min(length(app.PerfFilt), plateau_end);

            % Safety clamp 2
            if plateau_end <= plateau_start
                uialert(app.UIFigure, ...
                    'Ramp segments overlap — no plateau between them. Please re-select.', ...
                    'Segment Error');
                % Clean up any drawn lines
                if isvalid(app.ramp1Line), delete(app.ramp1Line); end
                if isvalid(app.ramp2Line), delete(app.ramp2Line); end
                return;
            end


            %app.Segments.Plateau = [plateau_start, plateau_end];
            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                app.Segments(ii).Plateau = [plateau_start, plateau_end];
            else
                app.Segments.Plateau = [plateau_start, plateau_end];
            end

            app.plateauLine = plot(app.UIAxes, x(plateau_start:plateau_end), app.PerfFilt(plateau_start:plateau_end), ...
                'Color',[0.8 0 0.8], 'LineWidth', 2.5);

            % Extract forcewave + targetwave from plateau
            idx1 = plateau_start;
            idx2 = plateau_end;


            %Ask user to draw a new rectangle
            % uialert(app.UIFigure, 'Draw a rectangle around the middle section.', 'Select Segment');
            % app.roi = drawrectangle(app.UIAxes);   % store handle
            % %Get rectangle bounds
            % pos = app.roi.Position;
            % idx1 = round(pos(1));
            % idx2 = round(pos(1) + pos(3));

            % Extract new segment
            app.forcewave  = app.PerfFilt(idx1:idx2);
            % Extract target segment depending on rig + analysis
            if strcmp(app.AnalysisDropDown.Value, 'MCON')
                % MCON ALWAYS has a target, even Derby Chair
                app.targetwave = app.TargetFilt(idx1:idx2);
            elseif strcmp(app.AnalysisDropDown.Value, 'TRAP') && ~strcmp(app.RigDropDown.Value, 'Derby Chair')
                % TRAP has a target ONLY for non-Derby rigs
                if ~isempty(app.TargetFilt)
                    app.targetwave = app.TargetFilt(idx1:idx2);
                else
                    app.targetwave = [];
                end
            else
                % TRAP Derby Chair → no target
                app.targetwave = [];
            end

            % cla(app.UIAxes);
            % plot(app.UIAxes, app.TargetFilt, 'b'); hold(app.UIAxes, 'on');
            % plot(app.UIAxes, app.PerfFilt, 'r', 'LineWidth',2);

            % % Highlight the selected region
            % hold(app.UIAxes, 'on');
            % app.highlightLine = plot(app.UIAxes, idx1:idx2, app.forcewave, ...
            %     'Color',[0 0.7 0], 'LineWidth', 2.5);
            % hold(app.UIAxes, 'off');

            % give it some breathing room on either side
            %xlim(app.UIAxes, [round(idx1*0.9) round(idx2*1.05)]);

            app.ComputeMetricsButton.Enable = 'on';
            app.ExportResultsButton.Enable = 'off';

        end

        % Button pushed function: ComputeMetricsButton
        function ComputeMetricsButtonPushed(app, event)

            if isempty(app.forcewave) && ~strcmp(app.AnalysisDropDown.Value,'MVC')
                uialert(app.UIFigure, ...
                    'No segment selected. Please select a segment first.', ...
                    'Compute Error');
                return;
            end

            if ~isempty(app.UITable.Data)
                uialert(app.UIFigure, ...
                    'Results already exist. Press Reset before recomputing.', ...
                    'Compute Error');
                return;
            end

            % need an MVC file checker here
            % --- MVC: no segments, no ramps, just max of filtered trace ---
            if strcmp(app.AnalysisDropDown.Value, 'MVC')

                if strcmpi(app.ModeDropDown.Value, 'Multi Channel') && ...
                        isfield(app.Data,'ForceL') && isfield(app.Data,'ForceR') && ...
                        strcmpi(app.ChannelDropDown.Value, 'Bilateral')

                    rig = app.RigDropDown.Value; % adjust to your actual rig source
                    [mvcBi, mvcL, mvcR, ~] = computeBilateralMVC(app.Data, rig, app.Calib, app.CutoffHzEditField.Value);

                    app.metrics = struct('MVC_Bilat', mvcBi, 'MVC_L', mvcL, 'MVC_R', mvcR);
                    app.Results = struct('Code', app.Code, ...
                        'MVC_Bilat', mvcBi, 'MVC_L', mvcL, 'MVC_R', mvcR);
                else


                    app.metrics = struct('MVC', max(app.PerfFilt));
                    app.Results = struct('Code', app.Code, 'MVC', app.metrics.MVC);
                end
                app.ResultsRamp = [];
                app.ResultsCombined = app.Results;

                T = struct2table(app.ResultsCombined);
                app.UITable.Data = T;
                app.UITable.ColumnName = T.Properties.VariableNames;
                app.UITable.ColumnWidth = 'auto';

                app.ExportResultsButton.Enable = 'on';
                return;
            end
            
            isMulti = strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi');
            % check for multi contraction
            if isMulti
                ii = app.CurrentContraction;
                % Override segment indices for this contraction
                segRamp1 = app.Segments(ii).Ramp1;
                segRamp2 = app.Segments(ii).Ramp2;
                %segPlate = app.Segments(ii).Plateau;
                % Use contraction-specific filtered force
                F = app.Data(ii).ForceFilt;
                % Extract ramp waves
                upramp   = F(segRamp1(1):segRamp1(2));
                downramp = F(segRamp2(1):segRamp2(2));
                % No target for TRAP_multi
                Targ_upramp   = [];
                Targ_downramp = [];
            else
                % get ramp results
                upramp   = app.PerfFilt(app.Segments.Ramp1(1):app.Segments.Ramp1(2));
                downramp = app.PerfFilt(app.Segments.Ramp2(1):app.Segments.Ramp2(2));
                if ~isempty(app.TargetFilt)
                    Targ_upramp   = app.TargetFilt(app.Segments.Ramp1(1):app.Segments.Ramp1(2));
                    Targ_downramp = app.TargetFilt(app.Segments.Ramp2(1):app.Segments.Ramp2(2));
                else
                    Targ_upramp   = [];
                    Targ_downramp = [];
                end

            end
            % getting this for extra Ramp analysis
            app.metricsRamp = computeRampStuff(upramp, downramp,Targ_upramp, Targ_downramp);
            app.ResultsRamp = buildRampResultsStruct(app.Code, app.metricsRamp);

            % adding this in for MCON cross corr
            if strcmpi(app.ModeDropDown.Value,'Multi Channel')
                extra.LeftRaw  = app.Data.ForceL;   % or ForceL if you prefer
                extra.RightRaw = app.Data.ForceR;
                extra.segment  = app.Segments.Plateau;
                extra.fs       = app.Data.fs;
                extra.cutoff   = app.CutoffHzEditField.Value;
            else
                extra = [];
            end

            % % % CALCULATE METRICS HERE
            if contains(app.AnalysisDropDown.Value, 'TRAP')
                if isMulti
                    fs = app.Data(ii).fs;
                else
                    fs = app.Data.fs;
                end

                if ~any(app.CoVwinEditField.Value)
                    covWin = [];
                else
                    covWin = app.CoVwinEditField.Value;
                end

                app.metrics = computeTRAP(app.forcewave, fs, extra,covWin);
                app.Results = buildTRAPResultsStruct(app.Code, app.metrics);
            elseif strcmp(app.AnalysisDropDown.Value, 'RAMP')
                app.Results = [];
            else
                app.metrics = computeMetrics(app.forcewave, app.targetwave, extra);
                app.Results = buildResultsStruct(app.Code, app.metrics);
            end

            %  TRAP Plateau PSD Frequency Bands Analysis ---
            if contains(app.AnalysisDropDown.Value, 'TRAP')
                % Determine sampling rate and plateau segment indices
                if isMulti
                    fs_psd = app.Data(ii).fs;
                else
                    fs_psd = app.Data.fs;
                end
   

                % Compute PSD frequency bands across plateau
                psdBands = computeSpectralBands(app.forcewave, fs_psd);
                plotBandsTFS(psdBands);

                % Append absolute band powers (N^2) to metrics and Results table
                app.metrics.PSD_Delta     = psdBands.Delta;
                app.metrics.PSD_Theta     = psdBands.Theta;
                app.metrics.PSD_Alpha     = psdBands.Alpha;
                app.metrics.PSD_Beta      = psdBands.Beta;
                app.metrics.PSD_Total     = psdBands.Total;

                app.Results.PSD_Delta     = psdBands.Delta;
                app.Results.PSD_Theta     = psdBands.Theta;
                app.Results.PSD_Alpha     = psdBands.Alpha;
                app.Results.PSD_Beta      = psdBands.Beta;
                app.Results.PSD_Total     = psdBands.Total;

                % Append relative band powers (%) to metrics and Results table
                app.metrics.PSD_Delta_Rel = psdBands.Delta_Rel;
                app.metrics.PSD_Theta_Rel = psdBands.Theta_Rel;
                app.metrics.PSD_Alpha_Rel = psdBands.Alpha_Rel;
                app.metrics.PSD_Beta_Rel  = psdBands.Beta_Rel;

                app.Results.PSD_Delta_Rel = psdBands.Delta_Rel;
                app.Results.PSD_Theta_Rel = psdBands.Theta_Rel;
                app.Results.PSD_Alpha_Rel = psdBands.Alpha_Rel;
                app.Results.PSD_Beta_Rel  = psdBands.Beta_Rel;
            end

            %disp(app.metrics)
            CombinedResults = app.Results;
            if ~isempty(app.ResultsRamp)
                fn = fieldnames(app.ResultsRamp);
                for k = 1:numel(fn)
                    CombinedResults.(fn{k}) = app.ResultsRamp.(fn{k});
                end
            end

            if isMulti
                if isempty(fieldnames(app.ResultsCombined))
                    app.ResultsCombined = repmat(CombinedResults, 1, numel(app.Data));
                end
                app.ResultsCombined(ii) = CombinedResults;
            else
                app.ResultsCombined = CombinedResults;
            end
            
            %  now show table
            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                T = struct2table(app.ResultsCombined(ii));   % only one row
            else
                T = struct2table(app.ResultsCombined);      % normal behaviour
            end
            app.UITable.Data = T;
            app.UITable.ColumnName = T.Properties.VariableNames;
            app.UITable.ColumnWidth = 'auto';

            app.ExportResultsButton.Enable = 'on';


        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            if isempty(app.ResultsCombined)
                uialert(app.UIFigure, 'No results to export. Compute metrics first.', 'Export Error');
                return;
            end

            % CombinedResults = app.Results;   % start with main results
            % if ~isempty(app.ResultsRamp)
            %     fn = fieldnames(app.ResultsRamp);
            %     for k = 1:numel(fn)
            %         CombinedResults.(fn{k}) = app.ResultsRamp.(fn{k});
            %     end
            % end


            % Convert struct to table
            %ResultsTable = struct2table(app.Results);
            %ResultsTable = struct2table(CombinedResults);

            %ResultsTable = struct2table(app.ResultsCombined);
            
            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                ii = app.CurrentContraction;
                ResultsTable = struct2table(app.ResultsCombined(ii));   % only one row
            else
                ResultsTable = struct2table(app.ResultsCombined);      % normal behaviour
            end

            % custom ordering as per Nikki
            % --- Reorder columns for export ---
            desiredOrder = {'Code','SteadyMean','SteadySTD','SteadyCoV','CoV2s','CoV5s', ...
                'CoVminus1','MeanYank','RMSYank','ApEn','SampEn','DFA57','MinForce','MaxForce', ...
                'Mean2s','Mean5s','STD2s','STD5s', ...
                'Up_SD','Up_RMSE','Up_RMSE_Norm','Down_SD','Down_RMSE','Down_RMSE_Norm'};

            existingCols = ResultsTable.Properties.VariableNames;
            orderedCols  = desiredOrder(ismember(desiredOrder, existingCols));
            remainingCols = setdiff(existingCols, orderedCols, 'stable');
            ResultsTable = ResultsTable(:, [orderedCols, remainingCols]);
            % --- --------------------------- --- 
            % FILENAME
            redoSuffix = "";
            if app.RedoCheckBox.Value
                redoSuffix = "_redo";
            end
            % Extract original file path and name
            [path, base, ~] = fileparts(app.FilePath);

            timestamp = string(datetime('now','Format','yyyyMMdd_HHmmss'));

            % Build new filename
            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                label = app.ContractionLabels{ii};
                suffix = "_con" + string(ii) + "_" + label;
            else
                suffix = "";
            end
            %newname = base + suffix + "_" + timestamp + "_forceresults.csv";
            newname = base + suffix + "_" + timestamp + "_forceresults" + redoSuffix + '.csv';
            % Full path for saving
            fullpath = fullfile(path, newname);

            % Try saving
            try
                writetable(ResultsTable, fullpath);
                uialert(app.UIFigure, "Results saved as " + newname, "Export Successful");
            catch ME
                uialert(app.UIFigure, ME.message, "Export Error");
            end

            % MAT file export
            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                label = app.ContractionLabels{ii};
                suffix = "_con" + string(ii) + "_" + label;
            else
                suffix = "";
            end
            %matname = base + suffix + "_" + timestamp + "_fullstate.mat";
            matname = base + suffix + "_" + timestamp + "_fullstate" + redoSuffix + '.mat';
            %matname = base + "_" + timestamp + "_fullstate.mat";
            matpath = fullfile(path, matname);

            % MAT file export
            S = struct();
            S.FilePath        = app.FilePath;
            S.Data            = app.Data;
            S.metrics         = app.metrics;
            S.metricsRamp     = app.metricsRamp;
            S.Results         = app.Results;
            S.ResultsRamp     = app.ResultsRamp;
            S.ResultsCombined = app.ResultsCombined;
            S.Code            = app.Code;

            % Signals and segments (unchanged)
            S.PerfFilt        = app.PerfFilt;
            S.TargetFilt      = app.TargetFilt;
            S.forcewave       = app.forcewave;
            S.targetwave      = app.targetwave;
            S.Segments        = app.Segments;

            save(matpath, '-struct', 'S');

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % 1. Remove rectangle if it exists
            if ~isempty(app.roi) && isvalid(app.roi)
                delete(app.roi);
                app.roi = [];
            end

            % 2. Remove highlight line if it exists
            if ~isempty(app.highlightLine) && isvalid(app.highlightLine)
                delete(app.highlightLine);
                app.highlightLine = [];
            end

            % Delete ramp 1 highlight
            if isprop(app,'ramp1Line') && ~isempty(app.ramp1Line) && isvalid(app.ramp1Line)
                delete(app.ramp1Line);
                app.ramp1Line = [];
            end

            % Delete plateau highlight
            if isprop(app,'plateauLine') && ~isempty(app.plateauLine) && isvalid(app.plateauLine)
                delete(app.plateauLine);
                app.plateauLine = [];
            end

            % Delete ramp 2 highlight
            if isprop(app,'ramp2Line') && ~isempty(app.ramp2Line) && isvalid(app.ramp2Line)
                delete(app.ramp2Line);
                app.ramp2Line = [];
            end

            % 3. Reset zoom
            xlim(app.UIAxes, 'auto');
            ylim(app.UIAxes, 'auto');
            grid(app.UIAxes,'on')

            % 4. Clear the table
            app.UITable.Data = [];


            % % Clear stored results
            app.Results = [];
            app.ResultsRamp = [];
            app.ResultsCombined = [];
            app.metrics = [];
            app.metricsRamp = [];
            % 
            % % nuclear
            % app.Data = [];
            % app.Segments = [];
            % app.Perf = [];
            % app.PerfFilt = [];
            % app.Code = [];
            % app.FileP
            % % Clear selected segment data
            % app.forcewave = [];
            % app.targetwave = [];
            
            if ~strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                legend(app.UIAxes, {'TargetFilt','PerfFilt'}, 'FontSize',8);
            end

            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                ii = app.CurrentContraction;

                % Clear segmentation for this contraction
                if ~isempty(app.Segments) && numel(app.Segments) >= ii
                    app.Segments(ii).Ramp1   = [];
                    app.Segments(ii).Ramp2   = [];
                    app.Segments(ii).Plateau = [];
                end

                % Clear results for this contraction
                if ~isempty(app.ResultsCombined) && numel(app.ResultsCombined) >= ii

                    emptyStruct = cell2struct( ...
                        repmat({[]}, 1, numel(fieldnames(app.ResultsCombined))), ...
                        fieldnames(app.ResultsCombined), ...
                        2);

                    app.ResultsCombined(ii) = emptyStruct();   % empty placeholder
                end
            end


            %keyboard
            app.FilterButton.Enable = 'on';   % file is still loaded
            app.SelectSegmentButton.Enable = 'on';
            app.ComputeMetricsButton.Enable = 'off';
            app.ExportResultsButton.Enable = 'off';

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            mode = app.ModeDropDown.Value;
            % if strcmp(mode, 'Multi Channel')
            %     app.ChannelDropDown.Enable = 'on';
            % else
            %     app.ChannelDropDown.Enable = 'off';
            % end
        end

        % Value changed function: AnalysisDropDown
        function AnalysisDropDownValueChanged(app, event)
            mode = app.AnalysisDropDown.Value;
            app.RigDropDown.Enable = 'on';
            if strcmp(mode, 'TRAP_multi')
                app.MultiDropDown.Enable = 'on';
            else
                app.MultiDropDown.Enable = 'off';
            end

            if strcmp(mode, 'MVC')
                app.ModeDropDown.Value = 'Multi Channel';
                app.ModeDropDown.Enable = 'off';
            else
                app.ModeDropDown.Enable = 'on';
            end

        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            % Only update if a file is loaded
            if isempty(app.Data)
                return;
            end

            % Clear axes
            cla(app.UIAxes);

            % Reset buttons because this is a new view
            app.FilterButton.Enable = 'on';
            app.SelectSegmentButton.Enable = 'off';
            app.ComputeMetricsButton.Enable = 'off';
            app.ExportResultsButton.Enable = 'off';

            % Clear any previous segment or results
            app.forcewave = [];
            app.targetwave = [];
            app.UITable.Data = [];

            if ~isempty(app.PerfFilt)
                % A filter has already been run — rerun it for the new channel
                % (this recomputes PerfFilt/TargetFilt and reuses the segment)
                app.FilterButtonPushed(event);
            else

                % Select the correct performance channel
                if strcmp(app.ModeDropDown.Value,'Multi Channel')
                    switch app.ChannelDropDown.Value
                        case 'Left'
                            perf = app.Data.ForceL;
                            target = getTargetOrEmpty(app.Data, 'TargetL');
                        case 'Right'
                            perf = app.Data.ForceR;
                            target = getTargetOrEmpty(app.Data, 'TargetR');
                        case 'Bilateral'
                            perf = app.Data.ForceBi;
                            target = getTargetOrEmpty(app.Data, 'TargetBi');
                    end

                    x = getTimeAxis(app.Data, length(perf));

                    plot(app.UIAxes, x, perf, 'r', 'LineWidth', 2);
                    hold(app.UIAxes, 'on');
                    if ~isempty(target)
                        app.targetPlot = plot(app.UIAxes,x, target, 'b'); hold(app.UIAxes, 'off');
                        legend(app.UIAxes, {'Target','Perf'}, 'FontSize', 8);
                    end
                    legend(app.UIAxes, {'Perf'}, 'FontSize', 8);

                elseif strcmp(app.ModeDropDown.Value,'Dual Channel')
                    switch app.ChannelDropDown.Value
                        case 'Left'
                            perf = app.Data.ForceL;
                        case 'Right'
                            perf = app.Data.ForceR;
                        case 'Bilateral'
                            if isfield(app.Data,'ForceBi')
                                perf = app.Data.ForceBi;
                            else
                                uialert(app.UIFigure, ...
                                    sprintf("Please just select left or right channel"), ...
                                    'Dual Channel Error');
                            end

                    end

                    x = getTimeAxis(app.Data, length(perf));

                    plot(app.UIAxes, x, perf, 'r', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                    legend(app.UIAxes, {'Perf'}, 'FontSize', 8);

                else

                    %single channel swapping!
                    perf = app.Data.Force;

                    x = getTimeAxis(app.Data, length(perf));
                    if isfield(app.Data,'Target')
                        if ~isempty(app.Data.Target)
                            app.targetPlot = plot(app.UIAxes, x, target, 'b'); hold(app.UIAxes, 'on');
                        end
                    end
                    plot(app.UIAxes, x, perf, 'r', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                    legend(app.UIAxes, {'Target','Perf'}, 'FontSize', 8);

                end
            end

            app.FilterButton.Enable = 'on';


        end

        % Value changed function: RigDropDown
        function RigDropDownValueChanged(app, event)
            rig = app.RigDropDown.Value;

            if strcmp(rig, 'Derby Chair')
                app.ModeDropDown.Value = 'Single Channel';
                app.ModeDropDown.Enable = 'off';
                app.ChannelDropDown.Enable = 'off';
            else
                app.ModeDropDown.Enable = 'on';
                app.ChannelDropDown.Enable = 'on';
            end

        end

        % Value changed function: ShowTargetCheckBox
        function ShowTargetCheckBoxValueChanged(app, event)

            if isempty(app.TargetFilt)
                app.ShowTargetCheckBox.Value = false;
                uialert(app.UIFigure, 'No target available for this trace.', 'No Target');
                return;
            end

            x = getTimeAxis(app.Data, length(app.TargetFilt));

            
            % If checkbox turned ON
            if app.ShowTargetCheckBox.Value
                % If target plot does NOT exist, create it
                if isempty(app.targetPlot) || ~isvalid(app.targetPlot)
                    hold(app.UIAxes,'on');
                    app.targetPlot = plot(app.UIAxes, x, app.TargetFilt, 'b');
                    hold(app.UIAxes,'off');
                end

                app.targetPlot.Visible = 'on';
                legend(app.UIAxes, {'Target','Perf'});

            else
                % Checkbox turned OFF
                if ~isempty(app.targetPlot) && isvalid(app.targetPlot)
                    app.targetPlot.Visible = 'off';
                end
                legend(app.UIAxes, {'Perf'});
            end
        end

        % Button pushed function: EditCalibButton
        function EditCalibButtonPushed(app, event)
            file = which('defaultCalibration.m');
            if ~isempty(file)
                winopen(file);   % opens in MATLAB editor
            else
                uialert(app.UIFigure, 'defaultCalibration.m not found.', 'Error');
            end
        end

        % Value changed function: MultiDropDown
        function MultiDropDownValueChanged(app, event)
            % Get selected contraction index
            label = app.MultiDropDown.Value;      % e.g. "Contraction 3"
            idx   = sscanf(label, 'Con %d');    % → 3

            app.CurrentContraction = idx;

            % Extract data for this contraction
            F = app.Data(idx).ForceFilt;
            T = app.Data(idx).Time;

            app.PerfFilt   = F;
            % ignore target for now
            

            % Plot it
            cla(app.UIAxes);
            plot(app.UIAxes, T, F, 'r', 'LineWidth', 2);
            title(app.UIAxes, sprintf('Con %d', idx));
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Force (N)');
            hold(app.UIAxes, 'on');

            if app.ShowTargetCheckBox.Value

                if isfield(app.Data(idx),'Target')
                    
                    app.targetPlot = plot(app.UIAxes, T, app.Data(idx).Target, 'b', 'LineWidth', 2);
                end

            end

            % Disable segmentation buttons until user draws rectangles
            app.SelectSegmentButton.Enable  = 'on';
            app.ComputeMetricsButton.Enable = 'off';


        end

        % Value changed function: RedoCheckBox
        function RedoCheckBoxValueChanged(app, event)
            value = app.RedoCheckBox.Value;

            if value == true
                app.alreadyProcessed = 1;
            elseif value == false
                app.alreadyProcessed = 0;
            end

            
        end

        % Button pushed function: HardResetButton
        function HardResetButtonPushed(app, event)
            if ~isempty(app.roi) && isvalid(app.roi)
                delete(app.roi);
                app.roi = [];
            end

            % 2. Remove highlight line if it exists
            if ~isempty(app.highlightLine) && isvalid(app.highlightLine)
                delete(app.highlightLine);
                app.highlightLine = [];
            end

            % Delete ramp 1 highlight
            if isprop(app,'ramp1Line') && ~isempty(app.ramp1Line) && isvalid(app.ramp1Line)
                delete(app.ramp1Line);
                app.ramp1Line = [];
            end

            % Delete plateau highlight
            if isprop(app,'plateauLine') && ~isempty(app.plateauLine) && isvalid(app.plateauLine)
                delete(app.plateauLine);
                app.plateauLine = [];
            end

            % Delete ramp 2 highlight
            if isprop(app,'ramp2Line') && ~isempty(app.ramp2Line) && isvalid(app.ramp2Line)
                delete(app.ramp2Line);
                app.ramp2Line = [];
            end

            % nuclear option
            cla(app.UIAxes, 'reset');

            % 3. Reset zoom
            xlim(app.UIAxes, 'auto');
            ylim(app.UIAxes, 'auto');

            % 4. Clear the table
            app.UITable.Data = [];

            fieldsToClear = {
                'FilePath'
                'Data'
                'metrics'
                'Results'
                'Code'
                'PerfFilt'
                'TargetFilt'
                'forcewave'
                'targetwave'
                'roi'
                'highlightLine'
                'Perf'
                'Target'
                'ramp1ROI'
                'ramp1Line'
                'plateauROI'
                'plateauLine'
                'ramp2ROI'
                'ramp2Line'
                'Segments'
                'targetPlot'
                'metricsRamp'
                'ResultsRamp'
                'ResultsCombined'
                'CurrentContraction'
                'LeftScale'
                'RightScale'
                'alreadyProcessed'
                'CorrectionLabels'
                };

            for i = 1:numel(fieldsToClear)
                f = fieldsToClear{i};
                if isprop(app, f)
                    app.(f) = [];
                end
            end
            
            % if ~strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
            %     legend(app.UIAxes, {'TargetFilt','PerfFilt'}, 'FontSize',8);
            % end

            if strcmpi(app.AnalysisDropDown.Value, 'TRAP_multi')
                ii = app.CurrentContraction;

                % Clear segmentation for this contraction
                if ~isempty(app.Segments) && numel(app.Segments) >= ii
                    app.Segments(ii).Ramp1   = [];
                    app.Segments(ii).Ramp2   = [];
                    app.Segments(ii).Plateau = [];
                end

                % Clear results for this contraction
                if ~isempty(app.ResultsCombined) && numel(app.ResultsCombined) >= ii

                    emptyStruct = cell2struct( ...
                        repmat({[]}, 1, numel(fieldnames(app.ResultsCombined))), ...
                        fieldnames(app.ResultsCombined), ...
                        2);

                    app.ResultsCombined(ii) = emptyStruct();   % empty placeholder
                end
            end

            app.UIFigure.Name = 'forceGUI';

            %keyboard
            app.FilterButton.Enable = 'on';   % file is still loaded
            app.SelectSegmentButton.Enable = 'on';
            app.ComputeMetricsButton.Enable = 'off';
            app.ExportResultsButton.Enable = 'off';
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)

            % need to bring target along too, hence the if after each case

            if strcmp(app.ModeDropDown.Value, 'Multi Channel')
                switch app.ChannelDropDown.Value
                    case 'Left'
                        f = -app.Data.ForceL;
                        app.Data.ForceL = f - min(f);
                        app.PerfFilt = app.Data.ForceL;
                        if isfield(app.Data,'TargetL')
                            tA = app.Data.TargetL;
                            app.Data.TargetL = tA - min(tA);
                            app.TargetFilt = app.Data.TargetL;
                        end
                    case 'Right'
                        f = -app.Data.ForceR;
                        app.Data.ForceR = f - min(f);
                        app.PerfFilt = app.Data.ForceR;
                        if isfield(app.Data,'TargetR')
                            tA = app.Data.TargetR;
                            app.Data.TargetR = tA - min(tA);
                            app.TargetFilt = app.Data.TargetR;
                        end
                    case 'Bilateral'
                        f = -app.Data.ForceBi;
                        app.Data.ForceBi = f - min(f);
                        app.PerfFilt = app.Data.ForceBi;
                        if isfield(app.Data,'TargetBi')
                            tA = app.Data.TargetBi;
                            app.Data.TargetBi = tA - min(tA);
                            app.TargetFilt = app.Data.TargetBi;
                        end
                end

            elseif strcmp(app.ModeDropDown.Value, 'Dual Channel')
                switch app.ChannelDropDown.Value
                    case 'Left'
                        f = -app.Data.ForceL;
                        app.Data.ForceL = f - min(f);
                        app.PerfFilt = app.Data.ForceL;
                        % also apply to other channel - check this!
                        f = app.Data.ForceR;
                        app.Data.ForceR = f - min(f);
                        if isfield(app.Data,'TargetL')
                            tA = app.Data.TargetL;
                            app.Data.TargetL = tA - min(tA);
                            app.TargetFilt = app.Data.TargetL;
                        end
                        
                    case 'Right'
                        f = -app.Data.ForceR;
                        app.Data.ForceR = f - min(f);
                        app.PerfFilt = app.Data.ForceR;
                        % also apply to other channel - check why
                        f = app.Data.ForceL;
                        app.Data.ForceL = f - min(f);
                        if isfield(app.Data,'TargetR')
                            tA = app.Data.TargetR;
                            app.Data.TargetR = tA - min(tA);
                            app.TargetFilt = app.Data.TargetR;
                        end
                    case 'Bilateral'
                        uialert(app.UIFigure, ...
                            sprintf("Please just select left or right channel"), ...
                            'Dual Channel Error');

                end

            else
                f = -app.Data.Force;
                app.Data.Force = f - min(f);
                app.PerfFilt = app.Data.Force;
                if isfield(app.Data,'Target')
                    tA = app.Data.Target;
                    app.Data.Target = tA - min(tA);
                    app.TargetFilt = app.Data.Target;
                end

            end

            cla(app.UIAxes);
            x = getTimeAxis(app.Data, length(app.PerfFilt));

            plot(app.UIAxes, x, app.PerfFilt, 'r', 'LineWidth', 2);
            
            % fix for plotting flip error
            if ~isempty(app.TargetFilt) && length(app.TargetFilt) == length(x)
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, x, app.TargetFilt, 'b', 'LineWidth', 2);
            end
            % original
            % if isfield(app.Data,'Target'); hold(app.UIAxes, 'on');
            %     plot(app.UIAxes, x, app.TargetFilt, 'b', 'LineWidth', 2);
            % end

            
            grid(app.UIAxes, 'on');
            app.SelectSegmentButton.Enable = 'off';
            app.ComputeMetricsButton.Enable = 'off';
            app.ExportResultsButton.Enable = 'off';

        end

        % Value changed function: mVtoVCheckBox
        function mVtoVCheckBoxValueChanged(app, event)
            % option to divide by 1000 if data are in mV
            if app.mVtoVCheckBox.Value
                factor = 1/1000;
            else
                factor = 1000; % toggled off - undo
            end

            % fields to scale (skip fs, Time, Description)
            skip = {'fs','Time','Description','Data'};
            fn = fieldnames(app.Data);
            for ii = 1:numel(fn)
                f = fn{ii};
                if ~ismember(f, skip) && isnumeric(app.Data.(f))
                    app.Data.(f) = app.Data.(f) * factor;
                end
            end

            % rescale anything already extracted/filtered downstream
            extraFields = {'PerfFilt','TargetFilt','forcewave','targetwave', ...
                'Perf','Target'};
            for ii = 1:numel(extraFields)
                f = extraFields{ii};
                if ~isempty(app.(f))
                    app.(f) = app.(f) * factor;
                end
            end
            
        end

        % Button pushed function: ColourButton
        function ColourButtonPushed(app, event)
            c = uisetcolor(app.UIFigure.Color, 'Select Background Color');
            app.UIFigure.Color = c;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Force trace')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Tooltip = {'Load your .mat file'};
            app.LoadFileButton.Text = 'Load File';
            app.SelectSegmentButton = uibutton(app.UIFigure, 'push');
            app.SelectSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSegmentButtonPushed, true);
            app.SelectSegmentButton.Enable = 'off';
            app.SelectSegmentButton.Tooltip = {'Choose 2 rectangles. From bottom to top of up ramp. Then from top to bottom of down ramp.'};
            app.SelectSegmentButton.Text = 'Select Segment';
            app.ComputeMetricsButton = uibutton(app.UIFigure, 'push');
            app.ComputeMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeMetricsButtonPushed, true);
            app.ComputeMetricsButton.Enable = 'off';
            app.ComputeMetricsButton.Text = 'Compute Metrics';
            app.ExportResultsButton = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Enable = 'off';
            app.ExportResultsButton.Tooltip = {'Saves out .csv and .mat file without overwriting'};
            app.ExportResultsButton.Text = 'Export Results';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File'; 'Lag'; 'Corr'; 'AUC'; 'Norm AUC'; ''};
            app.UITable.RowName = {};
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Tooltip = {'This also includes the calibration. No need to filter if using multi contraction'};
            app.FilterButton.Text = 'Filter';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Soft reset. Clears the segments selected to draw again.'};
            app.ResetButton.Text = 'Reset';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Single Channel', 'Multi Channel', 'Dual Channel'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Tooltip = {'Single or Multi channel force traces?'};
            app.ModeDropDown.Value = 'Single Channel';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'Left', 'Right', 'Bilateral'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Tooltip = {'Single channel will pick up whatever is here fyi'};
            app.ChannelDropDown.Value = 'Left';
            app.AnalysisDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.UIFigure);
            app.AnalysisDropDown.Items = {'MCON', 'TRAP', 'TRAP_multi', 'RAMP', 'MVC'};
            app.AnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisDropDownValueChanged, true);
            app.AnalysisDropDown.Tooltip = {'Choose contraction type'};
            app.AnalysisDropDown.Value = 'TRAP';
            app.RigDropDownLabel = uilabel(app.UIFigure);
            app.RigDropDownLabel.Text = 'Rig';
            app.RigDropDown = uidropdown(app.UIFigure);
            app.RigDropDown.Items = {'None', 'Ankle', 'Hand', 'Knee', 'Arm', 'Derby Chair', 'Custom'};
            app.RigDropDown.ValueChangedFcn = createCallbackFcn(app, @RigDropDownValueChanged, true);
            app.RigDropDown.Tooltip = {'Choose your force load cell'};
            app.RigDropDown.Value = 'None';
            app.CutoffHzEditFieldLabel = uilabel(app.UIFigure);
            app.CutoffHzEditFieldLabel.Text = 'Cut off (Hz)';
            app.CutoffHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CutoffHzEditField.Tooltip = {'Enter cutoff frequency in Hz, i.e. where [b,a] = butter(5,cutoff_frequency/fsamp*2,''low'');'};
            app.CutoffHzEditField.Value = 20;
            app.ShowTargetCheckBox = uicheckbox(app.UIFigure);
            app.ShowTargetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTargetCheckBoxValueChanged, true);
            app.ShowTargetCheckBox.Tooltip = {'This sometimes works'};
            app.ShowTargetCheckBox.Text = 'Show Target';
            app.EditCalibButton = uibutton(app.UIFigure, 'push');
            app.EditCalibButton.ButtonPushedFcn = createCallbackFcn(app, @EditCalibButtonPushed, true);
            app.EditCalibButton.Tooltip = {'This will open up defaultCalibration.m for manual adjustment'};
            app.EditCalibButton.Text = 'Edit Calib';
            app.MultiDropDownLabel = uilabel(app.UIFigure);
            app.MultiDropDownLabel.Text = 'Multi';
            app.MultiDropDown = uidropdown(app.UIFigure);
            app.MultiDropDown.ValueChangedFcn = createCallbackFcn(app, @MultiDropDownValueChanged, true);
            app.MultiDropDown.Enable = 'off';
            app.LeftEditFieldLabel = uilabel(app.UIFigure);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField.Tooltip = {'Keep at 0 to use default calibration'};
            app.RightEditFieldLabel = uilabel(app.UIFigure);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightEditField.Tooltip = {'Keep at 0 to use default calibration'};
            app.RedoCheckBox = uicheckbox(app.UIFigure);
            app.RedoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedoCheckBoxValueChanged, true);
            app.RedoCheckBox.Tooltip = {'Click this if you want to re-run a trial based on a saved mat file'};
            app.RedoCheckBox.Text = 'Redo?';
            app.HardResetButton = uibutton(app.UIFigure, 'push');
            app.HardResetButton.ButtonPushedFcn = createCallbackFcn(app, @HardResetButtonPushed, true);
            app.HardResetButton.Tooltip = {'Removes everything. Will need to load data again to continue.'};
            app.HardResetButton.Text = 'Hard Reset';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.Tooltip = {'Link to GitHub web page where this repository exists'};
            app.Hyperlink.URL = 'https://github.com/ppxma7/forceGUI';
            app.Hyperlink.Text = 'GitHub: forceGUI ';
            app.FlipButton = uibutton(app.UIFigure, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.SampFreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampFreqHzEditFieldLabel.Text = 'Samp Freq (Hz)';
            app.SampFreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoVwinEditFieldLabel = uilabel(app.UIFigure);
            app.CoVwinEditFieldLabel.Text = 'CoV win';
            app.CoVwinEditField = uieditfield(app.UIFigure, 'text');
            app.mVtoVCheckBox = uicheckbox(app.UIFigure);
            app.mVtoVCheckBox.ValueChangedFcn = createCallbackFcn(app, @mVtoVCheckBoxValueChanged, true);
            app.mVtoVCheckBox.Tooltip = {'Persistent divide by 1000'};
            app.mVtoVCheckBox.Text = 'mV to V?';
            app.ColourButton = uibutton(app.UIFigure, 'push');
            app.ColourButton.ButtonPushedFcn = createCallbackFcn(app, @ColourButtonPushed, true);
            app.ColourButton.Text = 'Colour';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = forceGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end